classdef MiniProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ChooseTheinitalparametersLabel  matlab.ui.control.Label
        PositionSeperatedbycommasEditField  matlab.ui.control.EditField
        PositionSeperatedbycommasLabel  matlab.ui.control.Label
        NoofbreakpointsEditField        matlab.ui.control.NumericEditField
        NoofbreakpointsEditFieldLabel   matlab.ui.control.Label
        LoadButton                      matlab.ui.control.Button
        EndpointEditField               matlab.ui.control.NumericEditField
        EndpointEditFieldLabel          matlab.ui.control.Label
        StartingpointEditField          matlab.ui.control.NumericEditField
        StartingpointEditFieldLabel     matlab.ui.control.Label
        SamplingFrequencyEditField      matlab.ui.control.NumericEditField
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        GeneralSignalGeneratorLabel     matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        StartButton                     matlab.ui.control.Button
        field2                          matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        field1                          matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        ChooseOperationDropDown         matlab.ui.control.DropDown
        ChooseOperationDropDownLabel    matlab.ui.control.Label
        OperationsLabel                 matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
       DialogWindow % Description
    end
    
    properties (Access = public)
    value
    Val1
    Val2
    Val3
    ResultantSignal
    Operation
    SamplingFrequency
    start
    finish

    end
    
    methods (Access = public)
        
        function updateValue(app,Text,V1,V2,V3)
            app.value = Text;
            app.Val1 = V1;
            app.Val2 = V2;
            app.Val3 = V3;
            
        end
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.field1.Visible = "off";
            app.EditFieldLabel.Visible = "off";
            app.field2.Visible = "off";
            app.EditField_2Label.Visible = "off";    
            app.ChooseOperationDropDown.Visible = "off";
            app.ChooseOperationDropDownLabel.Visible = "off";
            app.OperationsLabel.Visible = "off";
            app.StartButton.Visible = "off";
        end

        % Button pushed function: LoadButton
        function Load(app, event)
            app.SamplingFrequency = app.SamplingFrequencyEditField.Value;
            starttime = app.StartingpointEditField.Value;
            endtime = app.EndpointEditField.Value;
            app.start = starttime;
            app.finish = endtime;
            time = linspace(starttime,endtime,(endtime-starttime)*app.SamplingFrequency);
            BP = app.NoofbreakpointsEditField.Value;
            Points = app.PositionSeperatedbycommasEditField.Value;
            Points = str2num(Points);
            app.ResultantSignal = [];
            if BP == 0
                Points = [];
                timelist = [starttime endtime];
            else
                timelist = [starttime Points endtime];             
            end         
for i = 1:length(Points)+1
    app.DialogWindow = newWindow(app);
    waitfor(app.DialogWindow);
    tempt = linspace(timelist(i),timelist(i+1),(timelist(i+1)-timelist(i))*app.SamplingFrequency);
    if app.value == "DC"
        Signal = app.Val1*ones(1,length(tempt));
    elseif app.value =="Ramp"
         Signal = app.Val1*tempt+app.Val2;
    elseif app.value == "Polynomial"
         Signal =app.Val1*tempt.^app.Val2+app.Val3;
    elseif app.value == "Exponential"     
        Signal=app.Val1*(exp(app.Val2*(tempt)));        
    elseif app.value == "Sinusoidal"
        Signal =app.Val1*sin(2*pi*app.Val2*(tempt)+app.Val3);
    elseif app.value == "Sinc"
        Signal =app.Val1*sinc(tempt-app.Val2);
    elseif app.value == "Triangle"
        a=app.Val2-app.Val3.*0.5 ; 
        c=app.Val2+app.Val3.*0.5 ;
        Signal = app.Val1*triangularPulse(a,app.Val2,c,tempt);
    end
    app.ResultantSignal = [app.ResultantSignal Signal];
    
end
plot(app.UIAxes,time,app.ResultantSignal);
app.ChooseOperationDropDown.Visible = "on";
app.ChooseOperationDropDownLabel.Visible = "on";
app.OperationsLabel.Visible = "on";
       

        end

        % Value changed function: ChooseOperationDropDown
        function ChooseOperationDropDownValueChanged(app, event)
            app.Operation = app.ChooseOperationDropDown.Value;
            if app.Operation == "Amplitude scaling"
            app.field1.Visible = "on";
            app.EditFieldLabel.Text = "Scale";
            app.EditFieldLabel.Visible = "on";
            app.StartButton.Visible = "on";
            app.field2.Visible = "off";
            app.EditField_2Label.Visible = "off";
            elseif  app.Operation == "Time shift"
            app.field1.Visible = "on";
            app.EditFieldLabel.Text = "Shift";
            app.field2.Visible = "off";
            app.EditField_2Label.Visible = "off"; 

            app.EditFieldLabel.Visible = "on";
            app.StartButton.Visible = "on";
            elseif   app.Operation == "Expanding the signal" 
            app.field1.Visible = "on";
            app.EditFieldLabel.Text = "Expansion";
            app.field2.Visible = "off";
            app.EditField_2Label.Visible = "off"; 

            app.EditFieldLabel.Visible = "on";
            app.StartButton.Visible = "on";
            elseif  app.Operation == "Compressing the signal"
                app.field2.Visible = "off";
            app.EditField_2Label.Visible = "off"; 
            app.field1.Visible = "on";
             app.EditFieldLabel.Text = "Compression";
            app.EditFieldLabel.Visible = "on";
            app.StartButton.Visible = "on";
            elseif app.Operation == "Time reversal" || app.Operation == "The first derivative of the signal"
            app.field1.Visible = "off";
            app.EditFieldLabel.Visible = "off";
            app.StartButton.Visible = "on";
            app.field2.Visible = "off";
            app.EditField_2Label.Visible = "off"; 
            elseif app.Operation == "Clipping the signal"
                 app.field1.Visible = "on";
                 app.field2.Visible = "on";
            app.EditFieldLabel.Text = "Upper Clipping";
            app.EditField_2Label.Text = "Lower Clipping";
            app.EditFieldLabel.Visible = "on";
            app.EditField_2Label.Visible = "on";
            app.StartButton.Visible = "on";
            end

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.Operation == "Amplitude scaling"
                v1 = app.field1.Value;
                time = linspace(app.start,app.finish,(app.finish-app.start)*app.SamplingFrequency);
                app.ResultantSignal = v1*app.ResultantSignal;
            elseif  app.Operation == "Time shift"
                v1 = app.field1.Value;
                app.start = app.start + v1;
                app.finish = app.finish +v1;
                time = linspace(app.start,app.finish,(app.finish-app.start)*app.SamplingFrequency);
            elseif   app.Operation == "Expanding the signal" 
                v1 = app.field1.Value;
                app.start = app.start*v1;
                 app.finish = app.finish*v1;
               time = linspace(app.start,app.finish,(app.finish-app.start)*app.SamplingFrequency);
                 app.ResultantSignal = resample(app.ResultantSignal,v1,1);
            elseif  app.Operation == "Compressing the signal"
                 v1 = app.field1.Value;
                 app.start = app.start/v1;
                 app.finish = app.finish/v1;
                 time = linspace(app.start,app.finish,(app.finish-app.start)*app.SamplingFrequency);
                 app.ResultantSignal = downsample(app.ResultantSignal,v1);

            elseif app.Operation == "Time reversal" 
                time = linspace(-app.finish,-app.start,(app.finish-app.start)*app.SamplingFrequency);
                  app.ResultantSignal = app.ResultantSignal(end:-1:1);

            elseif app.Operation == "The first derivative of the signal"
                app.ResultantSignal= app.SamplingFrequency*diff(app.ResultantSignal);
                app.ResultantSignal = [app.ResultantSignal 0];
                time = linspace(app.start,app.finish,(app.finish-app.start)*app.SamplingFrequency);
            elseif app.Operation == "Clipping the signal"
                 v1 = app.field1.Value;
                 v2 = app.field2.Value;
                 I1= find (app.ResultantSignal> v1);
                 app.ResultantSignal(I1) = v1;
                 I2= find (app.ResultantSignal< v2);
                 app.ResultantSignal(I2)= v2;
                 time = linspace(app.start,app.finish,(app.finish-app.start)*app.SamplingFrequency);
            end
            plot(app.UIAxes,time,app.ResultantSignal);

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {275, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GeneralSignalGeneratorLabel = uilabel(app.LeftPanel);
            app.GeneralSignalGeneratorLabel.Text = 'General Signal Generator';
            app.SamplingFrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StartingpointEditFieldLabel = uilabel(app.LeftPanel);
            app.StartingpointEditFieldLabel.Text = 'Starting point';
            app.StartingpointEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EndpointEditFieldLabel = uilabel(app.LeftPanel);
            app.EndpointEditFieldLabel.Text = 'End point';
            app.EndpointEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @Load, true);
            app.LoadButton.Text = 'Load';
            app.NoofbreakpointsEditFieldLabel = uilabel(app.LeftPanel);
            app.NoofbreakpointsEditFieldLabel.Text = 'No. of breakpoints';
            app.NoofbreakpointsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PositionSeperatedbycommasLabel = uilabel(app.LeftPanel);
            app.PositionSeperatedbycommasLabel.Text = {'Position (Seperated'; ' by commas)'};
            app.PositionSeperatedbycommasEditField = uieditfield(app.LeftPanel, 'text');
            app.PositionSeperatedbycommasEditField.Value = '0';
            app.ChooseTheinitalparametersLabel = uilabel(app.LeftPanel);
            app.ChooseTheinitalparametersLabel.Text = 'Choose The inital parameters';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Signal Generator')
            xlabel(app.UIAxes, 'T')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.OperationsLabel = uilabel(app.RightPanel);
            app.OperationsLabel.Text = 'Operations';
            app.ChooseOperationDropDownLabel = uilabel(app.RightPanel);
            app.ChooseOperationDropDownLabel.Text = 'Choose Operation';
            app.ChooseOperationDropDown = uidropdown(app.RightPanel);
            app.ChooseOperationDropDown.Items = {'None', 'Amplitude scaling', 'Time reversal', 'Time shift', 'Expanding the signal', 'Compressing the signal', 'Clipping the signal', 'The first derivative of the signal'};
            app.ChooseOperationDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseOperationDropDownValueChanged, true);
            app.ChooseOperationDropDown.Value = 'None';
            app.EditFieldLabel = uilabel(app.RightPanel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.field1 = uieditfield(app.RightPanel, 'numeric');
            app.EditField_2Label = uilabel(app.RightPanel);
            app.EditField_2Label.Text = 'Edit Field';
            app.field2 = uieditfield(app.RightPanel, 'numeric');
            app.StartButton = uibutton(app.RightPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MiniProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end