classdef newWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LoadButton                   matlab.ui.control.Button
        Field3EditField              matlab.ui.control.NumericEditField
        Field3EditFieldLabel         matlab.ui.control.Label
        Field2EditField              matlab.ui.control.NumericEditField
        Field2EditFieldLabel         matlab.ui.control.Label
        Field1EditField              matlab.ui.control.NumericEditField
        Field1EditFieldLabel         matlab.ui.control.Label
        ChooseSignalDropDown         matlab.ui.control.DropDown
        ChooseSignalDropDownLabel    matlab.ui.control.Label
        SelectSignalParametersLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        value = ""; % Description

    end
    
    properties (Access = private)
        MainApp % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.MainApp = caller;
            app.Field1EditField.Visible = "off";
            app.Field1EditFieldLabel.Visible = "off";
            app.Field2EditField.Visible = "off";
            app.Field2EditFieldLabel.Visible = "off";
            app.Field3EditField.Visible = "off";
            app.Field3EditFieldLabel.Visible = "off";
            
        end

        % Value changed function: ChooseSignalDropDown
        function ChooseSignalDropDownValueChanged(app, event)
            app.value = app.ChooseSignalDropDown.Value;
            if app.value == "DC"
            app.Field1EditField.Visible = "on";
            app.Field1EditFieldLabel.Visible = "on";
            app.Field1EditFieldLabel.Text= "Amplitude";
            app.Field2EditField.Visible = "off";
            app.Field2EditFieldLabel.Visible = "off";
            app.Field3EditField.Visible = "off";
            app.Field3EditFieldLabel.Visible = "off";
            elseif app.value =="Ramp"
            app.Field1EditField.Visible = "on";
            app.Field1EditFieldLabel.Visible = "on";
            app.Field1EditFieldLabel.Text = "Slope";
            app.Field2EditFieldLabel.Text = "Interception";
            app.Field2EditField.Visible = "on";
            app.Field2EditFieldLabel.Visible = "on";
            app.Field3EditField.Visible = "off";
            app.Field3EditFieldLabel.Visible = "off";
            elseif app.value == "Polynomial"
                app.Field1EditField.Visible = "on";
            app.Field1EditFieldLabel.Visible = "on";
            app.Field1EditFieldLabel.Text = "Amplitude";
            app.Field2EditFieldLabel.Text = "Power";
            app.Field3EditFieldLabel.Text = "Interception";
            app.Field2EditField.Visible = "on";
            app.Field2EditFieldLabel.Visible = "on";
            app.Field3EditField.Visible = "on";
            app.Field3EditFieldLabel.Visible = "on";
            elseif app.value == "Exponential"
                app.Field1EditField.Visible = "on";
            app.Field1EditFieldLabel.Visible = "on";
                app.Field1EditFieldLabel.Text = "Amplitude";
            app.Field2EditFieldLabel.Text = "Exponent";
            app.Field2EditField.Visible = "on";
            app.Field2EditFieldLabel.Visible = "on";
            app.Field3EditField.Visible = "off";
            app.Field3EditFieldLabel.Visible = "off";
            elseif app.value == "Sinusoidal"
                app.Field1EditField.Visible = "on";
            app.Field1EditFieldLabel.Visible = "on";
                app.Field1EditFieldLabel.Text = "Amplitude";
            app.Field2EditFieldLabel.Text = "Frequency";
            app.Field3EditFieldLabel.Text = "Phase";
            app.Field2EditField.Visible = "on";
            app.Field2EditFieldLabel.Visible = "on";
            app.Field3EditField.Visible = "on";
            app.Field3EditFieldLabel.Visible = "on";
            elseif app.value == "Sinc"
                app.Field1EditField.Visible = "on";
            app.Field1EditFieldLabel.Visible = "on";
            app.Field1EditFieldLabel.Text = "Amplitude";
            app.Field2EditFieldLabel.Text = "Center Shift";
            app.Field2EditField.Visible = "on";
            app.Field2EditFieldLabel.Visible = "on";
            app.Field3EditField.Visible = "off";
            app.Field3EditFieldLabel.Visible = "off";
            elseif app.value == "Triangle"
                app.Field1EditField.Visible = "on";
            app.Field1EditFieldLabel.Visible = "on";
            app.Field1EditFieldLabel.Text = "Amplitude";
            app.Field2EditFieldLabel.Text = "Center Shift";
            app.Field3EditFieldLabel.Text = "Width";
            app.Field2EditField.Visible = "on";
            app.Field2EditFieldLabel.Visible = "on";
            app.Field3EditField.Visible = "on";
            app.Field3EditFieldLabel.Visible = "on";
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if app.value == "DC"
                Amplitude = app.Field1EditField.Value;
                updateValue(app.MainApp,app.value,Amplitude,0,0);
            elseif app.value =="Ramp"
                Slope = app.Field1EditField.Value;
                Interception = app.Field2EditField.Value;
                updateValue(app.MainApp,app.value,Slope,Interception,0);
            elseif app.value == "Polynomial"
                Amplitude = app.Field1EditField.Value;
                Power = app.Field2EditField.Value;
                Interception = app.Field3EditField.Value;
                updateValue(app.MainApp,app.value,Amplitude,Power,Interception);
            elseif app.value == "Exponential"
               Amplitude = app.Field1EditField.Value;
               Exponent = app.Field2EditField.Value;
               updateValue(app.MainApp,app.value,Amplitude,Exponent,0);
            elseif app.value == "Sinusoidal"
                  Amplitude = app.Field1EditField.Value;
                Frequency = app.Field2EditField.Value;
                Phase = app.Field3EditField.Value;
                updateValue(app.MainApp,app.value,Amplitude,Frequency,Phase);
            elseif app.value == "Sinc"
                  Amplitude = app.Field1EditField.Value;
                CenterShift = app.Field2EditField.Value;
                updateValue(app.MainApp,app.value,Amplitude,CenterShift,0);
            elseif app.value == "Triangle"
                 Amplitude = app.Field1EditField.Value;
                CenterShift = app.Field2EditField.Value;
                Width = app.Field3EditField.Value;
                updateValue(app.MainApp,app.value,Amplitude,CenterShift,Width);
            end
            closereq();
   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectSignalParametersLabel = uilabel(app.UIFigure);
            app.SelectSignalParametersLabel.Text = 'Select Signal Parameters';
            app.ChooseSignalDropDownLabel = uilabel(app.UIFigure);
            app.ChooseSignalDropDownLabel.Text = 'Choose Signal';
            app.ChooseSignalDropDown = uidropdown(app.UIFigure);
            app.ChooseSignalDropDown.Items = {'Choose Signal', 'DC', 'Ramp', 'Polynomial', 'Exponential', 'Sinusoidal', 'Sinc', 'Triangle'};
            app.ChooseSignalDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseSignalDropDownValueChanged, true);
            app.ChooseSignalDropDown.Value = 'Choose Signal';
            app.Field1EditFieldLabel = uilabel(app.UIFigure);
            app.Field1EditFieldLabel.Text = 'Field1';
            app.Field1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Field2EditFieldLabel = uilabel(app.UIFigure);
            app.Field2EditFieldLabel.Text = 'Field2';
            app.Field2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Field3EditFieldLabel = uilabel(app.UIFigure);
            app.Field3EditFieldLabel.Text = 'Field3';
            app.Field3EditField = uieditfield(app.UIFigure, 'numeric');
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = newWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end